classdef Deniz_Inci_EE101_TermProject1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AmplitudeKnobLabel  matlab.ui.control.Label
        ReverseSwitch       matlab.ui.control.Switch
        AmplitudeKnob       matlab.ui.control.Knob
        SpeedKnob           matlab.ui.control.Knob
        SpeedKnobLabel      matlab.ui.control.Label
        recordButton        matlab.ui.control.Button
        playButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: recordButton
        function recordButtonPushed(app, event)

audioObject = audiorecorder;

            Fs =44100;
           nBits =16;
           numChannels=1;
           nBitsChannels =2;
           ID=-1;
           recordObject = audiorecorder(Fs,nBits,numChannels,ID);
           duration = 5;
           msgbox("recording")
           recordblocking(recordObject, duration);
           y= getaudiodata(recordObject);
           audiowrite("myaudio.wav",y,Fs);
           msgbox("Recording completed.");


        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
[y,Fs] = audioread('myaudio.wav');
speed = app.SpeedKnob.Value;
Amplitude = app.AmplitudeKnob.Value;

reverse = app.ReverseSwitch.Value;
if strcmp(reverse, 'Reverse')
    y = flip(y);
end
y = y * Amplitude;

a = (0:length(y)-1) / Fs;
plot(app.UIAxes, a, y);
xlim(app.UIAxes, [0, max(a)]);
ylim(app.UIAxes, [0.2, 1.8] * Amplitude);

sound(y, Fs * speed);
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.recordButton = uibutton(app.UIFigure, 'push');
            app.recordButton.ButtonPushedFcn = createCallbackFcn(app, @recordButtonPushed, true);
            app.recordButton.Text = 'record';
            app.SpeedKnobLabel = uilabel(app.UIFigure);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.UIFigure, 'continuous');
            app.SpeedKnob.Limits = [0.2 1.8];
            app.SpeedKnob.Value = 0.2;
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0.2 1.8];
            app.AmplitudeKnob.Value = 0.2;
            app.ReverseSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReverseSwitch.Items = {'Normal', 'Reverse'};
            app.ReverseSwitch.Value = 'Normal';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Deniz_Inci_EE101_TermProject1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end